classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GraphTypeDropDownLabel       matlab.ui.control.Label
        GraphTypeDropDown            matlab.ui.control.DropDown
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField       matlab.ui.control.NumericEditField
        PlotDynamicsButton           matlab.ui.control.Button
        TimeEditFieldLabel           matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
        ClearButton                  matlab.ui.control.Button
        RandomInitialStateCheckBox   matlab.ui.control.CheckBox
        InitialStatex1xnLabel        matlab.ui.control.Label
        InitialStatex1xnEditField    matlab.ui.control.EditField
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function[A,G] = directed_cycle(app,n)
            A = zeros(n,n);
            A = diag(ones(n-1,1),1);
            A(n,1)=1;
            G = digraph(A);
        
        end
        
        function[A,G] = path(app,n)
            A = zeros(n,n);
            A = diag(ones(n-1,1),1)+diag(ones(n-1,1),-1);
            G = graph(A);
            
        end
        
        function[A,G] = complete_graph(app,n)
            A = ones(n,n)-diag(ones(n,1));
            V = 1:1:n;
            G = graph(A)
            
        end
        
%         function[A,G] = complete_bipartite(app,n)
%             
%             
%             A = zeros(n,n);
%             A(n/2+1:n,1:n/2)=ones(n-n/2,n/2)
%             A(1:n/2,n/2+1:n)=ones(n/2,n-n/2)
%             V = 1:1:n;
%             G = graph(A)
%             
%             
%         end
        
        function [a] = q(app,x)
            a = floor(x+0.5);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotDynamicsButton
        function PlotDynamicsButtonPushed(app, event)
    n = app.NumberofNodesEditField.Value;
    T = app.TimeEditField.Value;
    x = linspace(0,1);
    
    % values of dropdown menu type of graph, time and number of nodes
    value = app.GraphTypeDropDown.Value;
    N = app.NumberofNodesEditField.Value;
    T = app.TimeEditField.Value;
    h = 1e-3;
    n = T/h; 
    t = linspace(0,T,n);
    rng(2021)
    
    if app.RandomInitialStateCheckBox.Value==0
    test = app.RandomInitialStateCheckBox.Value
    a = app.InitialStatex1xnEditField.Value
    init_conditions = str2double(strsplit(a,','));
    
    else
        init_conditions = 10*rand(N,1);
    end
        
    
    if strcmpi(value,'complete')
        [A,G]= complete_graph(app,N)
        X = app_solve_dynamics(T,h,A,init_conditions)
        
        
        hold(app.UIAxes,'on')
        hold(app.UIAxes_2,'on')
        hold(app.UIAxes3,'on')
        plot(app.UIAxes3,G,'edgecolor','k','nodecolor','k','Layout','auto')
        for i=1:N
            plot(app.UIAxes,t,X(i,:))
            plot(app.UIAxes_2,t,q(app,X(i,:)))
        end
        hold(app.UIAxes,'off')
        hold(app.UIAxes_2,'off')
        hold(app.UIAxes3,'off')


%     elseif strcmpi(value,'completo bipartito')
%         
%         [A,G]= complete_bipartite(app,N)
%         X = app_solve_dynamics(T,h,A)
%         
%         hold(app.UIAxes,'on')
%         hold(app.UIAxes_2,'on')
%         hold(app.UIAxes3,'on')
%         plot(app.UIAxes3,G,'edgecolor','k','nodecolor','k','Layout','auto')
%         for i=1:N
%             plot(app.UIAxes,t,X(i,:))
%             plot(app.UIAxes_2,t,q(app,X(i,:)))
%         end
%         hold(app.UIAxes,'off')
%         hold(app.UIAxes_2,'off')
%         hold(app.UIAxes3,'off')
        
        
    elseif strcmpi(value,'directed cycle')
        [A,G]= directed_cycle(app,N)
        X = app_solve_dynamics(T,h,A,init_conditions)
        
         hold(app.UIAxes,'on')
        hold(app.UIAxes_2,'on')
        hold(app.UIAxes3,'on')
        plot(app.UIAxes3,G,'edgecolor','k','nodecolor','k','Layout','auto')
        for i=1:N
            plot(app.UIAxes,t,X(i,:))
            plot(app.UIAxes_2,t,q(app,X(i,:)))
        end
        hold(app.UIAxes,'off')
        hold(app.UIAxes_2,'off')
        hold(app.UIAxes3,'off')
        
        
    elseif strcmpi(value,'path')
        
         [A,G]= path(app,N)
        X = app_solve_dynamics(T,h,A,init_conditions)
        
         hold(app.UIAxes,'on')
        hold(app.UIAxes_2,'on')
        hold(app.UIAxes3,'on')
        plot(app.UIAxes3,G,'edgecolor','k','nodecolor','k','Layout','auto')
        for i=1:N
            plot(app.UIAxes,t,X(i,:))
            plot(app.UIAxes_2,t,q(app,X(i,:)))
        end
        hold(app.UIAxes,'off')
        hold(app.UIAxes_2,'off')
        hold(app.UIAxes3,'off')
        
    end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes3)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {498, 498};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GraphTypeDropDownLabel = uilabel(app.LeftPanel);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.LeftPanel);
            app.GraphTypeDropDown.Items = {'Complete', 'Directed Cycle', 'Path'};
            app.GraphTypeDropDown.Value = 'Complete';
            app.NumberofNodesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofNodesEditField.Value = 2;
            app.PlotDynamicsButton = uibutton(app.LeftPanel, 'push');
            app.PlotDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDynamicsButtonPushed, true);
            app.PlotDynamicsButton.Text = 'Plot Dynamics';
            app.TimeEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeEditField.Value = 1;
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RandomInitialStateCheckBox = uicheckbox(app.LeftPanel);
            app.RandomInitialStateCheckBox.Text = 'Random Initial State';
            app.InitialStatex1xnLabel = uilabel(app.LeftPanel);
            app.InitialStatex1xnLabel.Text = {'Initial State'; 'x1,...,xn'; ''};
            app.InitialStatex1xnEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Quantized Dynamics')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'state')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'quantized state')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Graph Topology')
            app.UIAxes3.XAxisLocation = 'origin';
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end